classdef RoboArmSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        ComputeButton                  matlab.ui.control.Button
        Theta1EditFieldLabel           matlab.ui.control.Label
        Theta1EditField                matlab.ui.control.NumericEditField
        Theta2EditFieldLabel           matlab.ui.control.Label
        Theta2EditField                matlab.ui.control.NumericEditField
        Theta3EditFieldLabel           matlab.ui.control.Label
        Theta3EditField                matlab.ui.control.NumericEditField
        Theta4EditFieldLabel           matlab.ui.control.Label
        Theta4EditField                matlab.ui.control.NumericEditField
        Theta5EditFieldLabel           matlab.ui.control.Label
        Theta5EditField                matlab.ui.control.NumericEditField
        Theta6EditFieldLabel           matlab.ui.control.Label
        Theta6EditField                matlab.ui.control.NumericEditField
        Theta7EditFieldLabel           matlab.ui.control.Label
        Theta7EditField                matlab.ui.control.NumericEditField
        DisplayOptionsPanel            matlab.ui.container.Panel
        EllipsoidSwitchLabel           matlab.ui.control.Label
        EllipsoidSwitch                matlab.ui.control.Switch
        ScrewAxesSwitchLabel           matlab.ui.control.Label
        ScrewAxesSwitch                matlab.ui.control.Switch
        JointCoordSwitchLabel          matlab.ui.control.Label
        JointCoordSwitch               matlab.ui.control.Switch
        EndEffectorCoordSwitchLabel    matlab.ui.control.Label
        EndEffectorCoordSwitch         matlab.ui.control.Switch
        DefaultButton                  matlab.ui.control.Button
        AngularEllipsoidPanel          matlab.ui.container.Panel
        IsotropyEditFieldLabel         matlab.ui.control.Label
        IsotropyEditField              matlab.ui.control.NumericEditField
        CondtitionEditFieldLabel       matlab.ui.control.Label
        CondtitionEditField            matlab.ui.control.NumericEditField
        VolumeEditFieldLabel           matlab.ui.control.Label
        VolumeEditField                matlab.ui.control.NumericEditField
        LinearEllipsoidPanel           matlab.ui.container.Panel
        VolumeEditField_2Label         matlab.ui.control.Label
        VolumeEditField_2              matlab.ui.control.NumericEditField
        CondtitionEditField_2Label     matlab.ui.control.Label
        CondtitionEditField_2          matlab.ui.control.NumericEditField
        IsotropyEditField_2Label       matlab.ui.control.Label
        IsotropyEditField_2            matlab.ui.control.NumericEditField
        DesiredconfigurationPanel      matlab.ui.container.Panel
        XEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        wXEditFieldLabel               matlab.ui.control.Label
        wXEditField                    matlab.ui.control.NumericEditField
        wYEditFieldLabel               matlab.ui.control.Label
        wYEditField                    matlab.ui.control.NumericEditField
        wZEditFieldLabel               matlab.ui.control.Label
        wZEditField                    matlab.ui.control.NumericEditField
        ThetaradianLabel               matlab.ui.control.Label
        ThetaradianEditField           matlab.ui.control.NumericEditField
        JacobianInversePanel           matlab.ui.container.Panel
        CalculateButton                matlab.ui.control.Button
        GraphButton                    matlab.ui.control.Button
        RedundancyresolutionPanel      matlab.ui.container.Panel
        CalculateButton_3              matlab.ui.control.Button
        GraphButton_3                  matlab.ui.control.Button
        JacobianTransposePanel         matlab.ui.container.Panel
        CalculateButton_2              matlab.ui.control.Button
        GraphButton_2                  matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        Lamp_2                         matlab.ui.control.Lamp
        Lamp_3                         matlab.ui.control.Lamp
        EndEffectorConfigurationPanel  matlab.ui.container.Panel
        XEditField_3Label              matlab.ui.control.Label
        XEditField_3                   matlab.ui.control.NumericEditField
        YEditField_3Label              matlab.ui.control.Label
        YEditField_3                   matlab.ui.control.NumericEditField
        ZEditField_3Label              matlab.ui.control.Label
        ZEditField_3                   matlab.ui.control.NumericEditField
        wXEditField_2Label             matlab.ui.control.Label
        wXEditField_2                  matlab.ui.control.NumericEditField
        wYEditField_2Label             matlab.ui.control.Label
        wYEditField_2                  matlab.ui.control.NumericEditField
        wZEditField_2Label             matlab.ui.control.Label
        wZEditField_2                  matlab.ui.control.NumericEditField
        ThetaradianEditField_2Label    matlab.ui.control.Label
        ThetaradianEditField_2         matlab.ui.control.NumericEditField
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
            L1 = 0.333;
            L2 = 0.316;
            L3 = 0.384;
            L4 = 0.088;
            L5 = 0.107;

            M;
            q;
            w;
            p;
            toggleEllipsoid;
            toggleScrewAxis;
            toggleJointCoord;
            toggleEndEffectorCoord;
           

            theta = [0 0 0 0 0 0 0];
            theta_inv = [0 0 0 0 0 0 0];
            theta_trans = [0 0 0 0 0 0 0];
            theta_redund = [0 0 0 0 0 0 0];
            
    end
    
    methods (Access = private)
        
        
        %----------------------------------------------------Space FK------------------------------------------
        
        function [FK, T_space] = FK_space(app,q, w, theta, M)
            n = size(q);
            v = zeros(n(1),3);
            s = zeros(n(1),6);
            T = zeros(4,4,n(1));
            T_space = zeros(4,4,n(1)+1);
            FK = eye(4);
            for i = 1: n(1)
                v(i,:) = cross(-w(i,:),q(i,:));
                s(i,:) = [w(i,:), v(i,:)];
                T(:,:,i) = getT(w(i,:),v(i,:),theta(i));
                FK = FK*T(:,:,i);
                T_space(:,:,i) = eye(4);
                for j = 1:i
                    T_space(:,:,i) = T_space(:,:,i)*T(:,:,j);
                end
                T_space(:,:,i) = T_space(:,:,i)*M(:,:,i);
            end
            
            FK = FK*M(:,:,n(1)+1);
            T_space(:,:,n(1)+1) = FK;
          
            
        end
        
        %----------------------------------------------------Draw Robot arm------------------------------------------
        
        function drawRoboArm(app,T_space)

                axis(app.UIAxes,[-0.7 0.7 -0.7 0.7 0 1.5]);
                %plot origin
                plot3(app.UIAxes,[0 1*0.05],[0 0],[0 0], 'r',...
                      [0 0],[0 1*0.05],[0 0], 'b',...
                      [0 0],[0 0],[0 1*0.05], 'g')
                  
          %plot arms

                h = plot3(app.UIAxes, [0, T_space(1,4,1)],[0, T_space(2,4,1)],[0, T_space(3,4,1)],'k',...
                                  [T_space(1,4,1), T_space(1,4,1+1)],[T_space(2,4,1) T_space(2,4,1+1)],[T_space(3,4,1) T_space(3,4,1+1)],'k',...
                                  [T_space(1,4,2), T_space(1,4,2+1)],[T_space(2,4,2) T_space(2,4,2+1)],[T_space(3,4,2) T_space(3,4,2+1)],'k',...
                                  [T_space(1,4,3), T_space(1,4,3+1)],[T_space(2,4,3) T_space(2,4,3+1)],[T_space(3,4,3) T_space(3,4,3+1)],'k',...
                                  [T_space(1,4,4), T_space(1,4,4+1)],[T_space(2,4,4) T_space(2,4,4+1)],[T_space(3,4,4) T_space(3,4,4+1)],'k',...
                                  [T_space(1,4,5), T_space(1,4,5+1)],[T_space(2,4,5) T_space(2,4,5+1)],[T_space(3,4,5) T_space(3,4,5+1)],'k',...
                                  [T_space(1,4,6), T_space(1,4,6+1)],[T_space(2,4,6) T_space(2,4,6+1)],[T_space(3,4,6) T_space(3,4,6+1)],'k',...
                                  [T_space(1,4,7), T_space(1,4,7+1)],[T_space(2,4,7) T_space(2,4,7+1)],[T_space(3,4,7) T_space(3,4,7+1)],'k');


                set(h, 'LineWidth', 3)          
                
        end
        
        %----------------------------------------------------Draw ellipsoid------------------------------------------
        
        function manip_ellip(app, J, FK)
            Jw = J(1:3,:);
            Jv = J(4:6,:);
            Aw = Jw*Jw';
            Av = Jv*Jv';
            [eigvectw, eigw] = eig(Aw);
            [eigvectv, eigv] = eig(Av);
            x_pos = FK(1,4);
            y_pos = FK(2,4);
            z_pos = FK(3,4);
         
            pos = [x_pos, y_pos, z_pos];
            
            T1 = eye(4);
            T2 = eye(4);
            
           
            %check whether the matrix is in SO(3)
            if sign(det(eigvectw)) == -1
               eigvectw(:,1) = -eigvectw(:,1);
            end
            
            if sign(det(eigvectv)) == -1
               eigvectv(:,1) = -eigvectv(:,1);
            end
            
  
             [xw, yw, zw]= ellipsoid(0,0,0,0.15*real(sqrt(eigw(3,3))),0.15*real(sqrt(eigw(2,2))),0.15*real(sqrt(eigw(1,1))),30);
             [xv, yv, zv]= ellipsoid(0,0,0,0.15*real(sqrt(eigv(3,3))),0.15*real(sqrt(eigv(2,2))),0.15*real(sqrt(eigv(1,1))),30);
            
            S1 = surf(app.UIAxes,xw, yw, zw,'facealpha', 0.1, 'facecolor', 'blue','Edgealpha',0.2);
            S2 = surf(app.UIAxes,xv, yv, zv,'facealpha', 0.1, 'facecolor', 'red','Edgealpha',0.2);
           
            
            t1 = hgtransform(app.UIAxes,'Parent',app.UIAxes);
            t2 = hgtransform(app.UIAxes,'Parent',app.UIAxes);
            set(S1,'Parent',t1);
            set(S2,'Parent',t2);
            T1(1:3,1:3) = eigvectw;
            T2(1:3,1:3) = eigvectv;
            T1(1:3,4) = pos;
            T2(1:3,4) = pos;
            set(t1,'Matrix',T1);
            set(t2,'Matrix',T2);


        end
        
         %----------------------------------------------------Space Jacobian------------------------------------------
        
        function [J] = J_space(app, q, w, theta, M)
            n = size(q);
            v = zeros(n(1),3);
            s = zeros(n(1),6);
            T = zeros(4,4,n(1));
            q_nonhome = zeros(n(1),3);
            w_nonhome = zeros(n(1),3);
            
            for i = 1: n(1)
                v(i,:) = cross(-w(i,:),q(i,:));
                T(:,:,i) = getT(w(i,:),v(i,:),theta(i));
                T_space = eye(4);
                for j = 1:i
                    T_space = T_space*T(:,:,j);
                end
                q_temp = T_space*M(:,:,i)*[0;0;0;1];
                q_nonhome(i, :) = q_temp(1:3);
                w_temp = T_space(1:3,1:3)*w(i,:)';
                w_nonhome(i, :) = w_temp;
                v(i,:) = cross(-w_nonhome(i, :), q_nonhome(i,:));
                s(i,:) = [w_nonhome(i, :), v(i,:)];
            end
             
            J = s';
        end
        
        
        %----------------------------------------------------draw joint coordinates------------------------------------------
        function drawJointCoord(app, T_space)
            n = size(T_space);
            n = n(3);
             for i = 1: n-1
                     plot3(app.UIAxes, [T_space(1,4,i) T_space(1,4,i)+ T_space(1,1,i)*0.05],[T_space(2,4,i) T_space(2,4,i)+ T_space(2,1,i)*0.05],[T_space(3,4,i) T_space(3,4,i)+ T_space(3,1,i)*0.05],'r',...
                           [T_space(1,4,i) T_space(1,4,i)+ T_space(1,2,i)*0.05],[T_space(2,4,i) T_space(2,4,i)+ T_space(2,2,i)*0.05],[T_space(3,4,i) T_space(3,4,i)+ T_space(3,2,i)*0.05],'b',...
                           [T_space(1,4,i) T_space(1,4,i)+ T_space(1,3,i)*0.05],[T_space(2,4,i) T_space(2,4,i)+ T_space(2,3,i)*0.05],[T_space(3,4,i) T_space(3,4,i)+ T_space(3,3,i)*0.05],'g')
             end
        end
        
        %----------------------------------------------------draw end effector coord-----------------------------------------
        function drawEndEffecotr(app, T_space)
             plot3(app.UIAxes,[T_space(1,4,8) T_space(1,4,8)+ T_space(1,1,8)*0.05],[T_space(2,4,8) T_space(2,4,8)+ T_space(2,1,8)*0.05],[T_space(3,4,8) T_space(3,4,8)+ T_space(3,1,8)*0.05],'r',...
                              [T_space(1,4,8) T_space(1,4,8)+ T_space(1,2,8)*0.05],[T_space(2,4,8) T_space(2,4,8)+ T_space(2,2,8)*0.05],[T_space(3,4,8) T_space(3,4,8)+ T_space(3,2,8)*0.05],'b',...
                              [T_space(1,4,8) T_space(1,4,8)+ T_space(1,3,8)*0.05],[T_space(2,4,8) T_space(2,4,8)+ T_space(2,3,8)*0.05],[T_space(3,4,8) T_space(3,4,8)+ T_space(3,3,8)*0.05],'g')
        end
        
        %----------------------------------------------------Isotropy-----------------------------------------
        function isotropy(app, eigw, eigv)
             
             eigenmaxw = round(max(eigw(:)),4);
             eigenminw = round(min(eigw(:)),4);
             isotropyw = sqrt(eigenmaxw)/sqrt(eigenminw);
             app.IsotropyEditField.Value = isotropyw;
            
             
             eigenmaxv = round(max(eigv(:)),4);
             eigenminv = round(min(eigv(:)),4);
             isotropyv = sqrt(eigenmaxv)/sqrt(eigenminv);
             app.IsotropyEditField_2.Value = isotropyv;
             
        end
        
        %----------------------------------------------------Condition-----------------------------------------
        function condition(app, eigw, eigv)
             
             eigenmaxw = round(max(eigw(:)),4);
             eigenminw = round(min(eigw(:)),4);
             conditionw = eigenmaxw/eigenminw;
             app.CondtitionEditField.Value = conditionw;

             eigenmaxv =  round(max(eigv(:)),4);
             eigenminv =  round(min(eigv(:)),4);
             conditionv = eigenmaxv/eigenminv;
             app.CondtitionEditField_2.Value = conditionv;

            
        end
        
        %----------------------------------------------------Volume-----------------------------------------
        function volume(app, Aw, Av)
             
             if isreal(sqrt(det(round(Aw,15))))
                volumew = sqrt(det(round(Aw,15)));
                app.VolumeEditField.Value = volumew;

             else
                 app.VolumeEditField.Value = 0;
             end
             
             if isreal(sqrt(det(round(Av,15))))
                volumev = sqrt(det(round(Av,15)));
                app.VolumeEditField_2.Value = volumev;
             else
                 app.VolumeEditField_2.Value = 0;
             end


        end


        function T = Tmat(app,waxis,p,theta)
            T = eye(4);
            rotation = rot(waxis,theta);
            T(1:3,1:3) = rotation;
            T(1:3, 4) = p;

        end
        
        function IK = J_inverse_kinematics(app, q, w, init_theta, M, config_desired)
            error = 0.0001;
        FK = FK_space(q,w, init_theta, M);
        Js = J_space(q,w, init_theta, M);
        Tbd = invT(FK)*config_desired;
        Vb = logmat(Tbd);
    %     Vb_temp = logm(Tbd);
    %     aVb = T2screw(Vb_temp);
        Jb = invAdjoint(FK)*Js;
        theta = init_theta';
        t = 1;
        while norm(Vb(1:3)) > error || norm(Vb(4:6)) > error
            theta = theta + pinv(Jb)*Vb;
            FK = FK_space(q,w, theta, M);
            Js = J_space(q,w, theta, M);
            Tbd = invT(FK)*config_desired;
            Vb = logmat(Tbd);
    %         Vb_temp = logm(Tbd);
    %         Vb = T2screw(Vb_temp);
            disp([norm(Vb(1:3)), norm(Vb(4:6))]);
            disp(Vb(1:3));
            Jb = invAdjoint(FK)*Js;  
        
            plot(app.UIAxes2,t,norm(Vb(1:3)),'-o','Color',[0,0.7,0.9]);
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2,t,norm(Vb(4:6)),'-*','Color',[0,0,0.9]);
            drawnow
            
            volume1 = real(round(sqrt(det(Jb(1:3,:)*Jb(1:3,:)')),4));
                volume2 = real(round(sqrt(det(Jb(4:6,:)*Jb(4:6,:)')),4));
                
                plot(app.UIAxes3,t,volume1,'-o','Color',[0,0.7,0.9]);
                hold(app.UIAxes3,'on')
                plot(app.UIAxes3,t,volume2,'-*','Color',[0,0,0.9]);
                drawnow
            t = t+1;
            end
            IK = theta;
                hold(app.UIAxes2,'off')
                hold(app.UIAxes3,'off')
        end
        
        function IK = J_transpose_kinematics(app, q, w, init_theta, M, config_desired)
                clf(app.UIAxes2);
                error = 0.001;
                FK = FK_space(q,w, init_theta, M); 
                Js = J_space(q,w, init_theta, M);
                Tbd = invT(FK)*config_desired; %body to desired config transformation
                Vb = logmat(Tbd);%error
                Jb = invAdjoint(FK)*Js;%body jacobian
                theta = init_theta';
                alpha = 0.13; %gain
                t = 1;
                norm1 = [];
                norm2 = [];
                volume1 = [];
                volume2 = [];
                while norm(Vb(1:3)) > error || norm(Vb(4:6)) > error
                    theta = theta + alpha*transpose(Jb)*Vb;
                    FK = FK_space(q,w, theta, M);
                    Js = J_space(q,w, theta, M);
                    Tbd = invT(FK)*config_desired;
                    Vb = logmat(Tbd);
                    Jb = invAdjoint(FK)*Js;  
                    disp(norm(Vb(1:3)));
                    norm1(t) = norm(Vb(1:3));
                    norm2(t) = norm(Vb(4:6));
                    
%                 plot(app.UIAxes2,t,norm(Vb(1:3)),'-o','Color',[0,0.7,0.9]);
%                 hold(app.UIAxes2,'on')
%                 plot(app.UIAxes2,t,norm(Vb(4:6)),'-*','Color',[0,0,0.9]);
%                 drawnow
%                 
                volume1(t) = real(round(sqrt(det(Jb(1:3,:)*Jb(1:3,:)')),4));
                volume2(t) = real(round(sqrt(det(Jb(4:6,:)*Jb(4:6,:)')),4));
                
%                 plot(app.UIAxes3,t,volume1,'-o','Color',[0,0.7,0.9]);
%                 hold(app.UIAxes3,'on')
%                 plot(app.UIAxes3,t,volume2,'-*','Color',[0,0,0.9]);
%                 drawnow
                
                
                t = t+1;
                end
                IK = theta;
                plot(app.UIAxes2,linspace(1,t,t-1),norm1,'-o','Color',[0,0.7,0.9]);
                hold(app.UIAxes2,'on')
                plot(app.UIAxes2,linspace(1,t,t-1),norm2,'-*','Color',[0,0,0.9]);
                
                plot(app.UIAxes3,linspace(1,t,t-1),volume1,'-o','Color',[0,0.7,0.9]);
                hold(app.UIAxes2,'on')
                plot(app.UIAxes3,linspace(1,t,t-1),volume2,'-*','Color',[0,0,0.9]);
                hold(app.UIAxes2,'off')
                hold(app.UIAxes2,'off')
        end
        
        function IK = redundancy_resolution2(app, q, w, init_theta, M, config_desired)
            clf(app.UIAxes2)
            error = 0.005;
            FK = FK_space(q,w, init_theta, M);
            Js = J_space(q,w, init_theta, M);
            n = size(q);
            delta = 0.001;
            Tbd = invT(FK)*config_desired;
            Vb = logmat(Tbd);
            Jb = invAdjoint(FK)*Js;
            theta = init_theta';
            gradient = zeros(1,n(1));
            volume = round(sqrt(det(Jb*Jb')),4);
            volume = real(volume);
            t = 1;
            while norm(Vb(1:3)) > error || norm(Vb(4:6)) > error
                    for i = 1: n(1)
                        theta_temp = theta;
                        theta_temp(i) = theta_temp(i)+delta;
                        Js = J_space(q,w, theta_temp, M);
                        FK = FK_space(q,w, theta_temp, M);
                        Jb = invAdjoint(FK)*Js;
                        d_volume = sqrt(det(Jb*Jb')) - volume;
                        gradient_i =  real(d_volume)/delta;
                        gradient(i) = gradient_i;
                    end
                 
                if volume < 0.01
                  theta = theta + 10*gradient';%300*(0.01-volume)*gradient';
                end
         
                
                FK = FK_space(q,w, theta, M);
                Js = J_space(q,w, theta, M);
                Tbd = invT(FK)*config_desired;
                Vb = logmat(Tbd);
                Jb = invAdjoint(FK)*Js;  
                theta = theta + pinv(Jb)*Vb;
                volume = round(sqrt(det(Jb*Jb')),4);
                volume = real(volume);
                
                 
                disp(norm(Vb(1:3)));
                %end
                %counter = counter+1;
                plot(app.UIAxes2,t,norm(Vb(1:3)),'-o','Color',[0,0.7,0.9]);
                hold(app.UIAxes2,'on')
                plot(app.UIAxes2,t,norm(Vb(4:6)),'-*','Color',[0,0,0.9]);
                drawnow
                
                volume1 = real(round(sqrt(det(Jb(1:3,:)*Jb(1:3,:)')),4));
                volume2 = real(round(sqrt(det(Jb(4:6,:)*Jb(4:6,:)')),4));
                
                plot(app.UIAxes3,t,volume1,'-o','Color',[0,0.7,0.9]);
                hold(app.UIAxes3,'on')
                plot(app.UIAxes3,t,volume2,'-*','Color',[0,0,0.9]);
                drawnow
                
                t = t+1;

              end
    IK = theta;
        hold(app.UIAxes2,'off')
                hold(app.UIAxes3,'off')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.M(:,:,1) = [1 0 0 0; 
                        0 1 0 0; 
                        0 0 1 app.L1; 
                        0 0 0 1];
                    
           app.M(:,:,2) = [1 0 0 0; 
                        0 0 -1 0; 
                        0 1 0 app.L1; 
                        0 0 0 1];
                    
           app.M(:,:,3) = [1 0 0 0; 
                        0 1 0 0; 
                        0 0 1 app.L1+app.L2; 
                        0 0 0 1];
                    
           app.M(:,:,4) = [1 0 0 app.L4; 
                        0 0 1 0; 
                        0 -1 0 app.L1+app.L2; 
                        0 0 0 1];
                    
           app.M(:,:,5) = [1 0 0 0; 
                        0 1 0 0; 
                        0 0 1 app.L1+app.L2+app.L3; 
                        0 0 0 1];
                    
           app.M(:,:,6) = [1 0  0 0; 
                        0 1  1 0; 
                        0 -1 0 app.L1+app.L2+app.L3; 
                        0 0  0 1];
            
           app.M(:,:,7) = [1 0 0 app.L4; 
                        0 -1 0 0; 
                        0  0 -1 app.L1+app.L2+app.L3; 
                        0 0 0 1];
            
           app.M(:,:,8) = [1  0 0  app.L4; 
                        0 -1 0  0; 
                        0  0 -1 app.L1+app.L2+app.L3-app.L5; 
                        0  0 0  1];
                    
                    
            app.q = [0 0 app.L1;
                 0 0 app.L1;
                 0 0 app.L1+app.L2;
                 app.L4 0 app.L1+app.L2;
                 0 0 app.L1+app.L2+app.L3;
                 0 0 app.L1+app.L2+app.L3;
                 app.L4 0 app.L1+app.L2+app.L3];
            
            app.w = [0 0 1;
                 0 1 0;
                 0 0 1;
                 0 -1 0;
                 0 0 1;
                 0 -1 0;
                 0 0 -1];
            
          
             [~, T_space] = FK_space(app,app.q, app.w, app.theta, app.M);
            
            
            drawRoboArm(app,T_space);
            
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            
            steps = 20;
            
            theta1 = app.Theta1EditField.Value;
            theta2 = app.Theta2EditField.Value;
            theta3 = app.Theta3EditField.Value;
            theta4 = app.Theta4EditField.Value;
            theta5 = app.Theta5EditField.Value;
            theta6 = app.Theta6EditField.Value;
            theta7 = app.Theta7EditField.Value;
  
            theta = [theta1 theta2 theta3 theta4 theta5 theta6 theta7];
            dtheta = (theta - app.theta)/steps;
            

            T_space_array = zeros(4,4,8,steps);
            J_array = zeros(6,7,steps);
            FK_array = zeros(4,4,steps);
            Aw = zeros(3,3,steps);
            Av = zeros(3,3,steps);
            eigw = zeros(3,steps);
            eigv = zeros(3,steps);
            
            for i = 1:steps
            [FK_array(:,:,i), T_space_array(:,:,:,i)] = FK_space(app,app.q, app.w, app.theta + i*dtheta, app.M);
            end
            
            for i = 1:steps
            J_array(:,:,i) = J_space(app,app.q, app.w, app.theta + i*dtheta, app.M);
            end
            
            for i = 1:steps
                Jw =  J_array(1:3,:,i);
                Jv =  J_array(4:6,:,i);
                Aw(:,:,i) = Jw*Jw';
                Av(:,:,i) = Jv*Jv';
            end
            
            for i = 1:steps
                [~, a] = eig(Aw(:,:,i));
                [~, b] = eig(Av(:,:,i));
                eigw(:,i) = [a(1,1); a(2,2); a(3,3)];
                eigv(:,i) = [b(1,1); b(2,2); b(3,3)];
            end


            % ----------------------------------------------------draw robot -----------------------------------------------------
            
           
            for i = 1:steps
               
                drawRoboArm(app, T_space_array(:,:,:,i));
                hold(app.UIAxes, "on")
                if app.toggleEllipsoid == 1
                    manip_ellip(app, J_array(:,:,i), FK_array(:,:,i));
                end
                
                
                if app.toggleEndEffectorCoord == 1
                    drawEndEffecotr(app, T_space_array(:,:,:,i))
                end
                
                if app.toggleJointCoord == 1
                    drawJointCoord(app, T_space_array(:,:,:,i))
                end
                hold(app.UIAxes, "off")
                drawnow limitrate nocallbacks
                    
                H = FK_array(:,:,i);
                app.XEditField_3.Value = H(1,4);
                app.YEditField_3.Value = H(2,4);
                app.ZEditField_3.Value = H(3,4);
                
              
                isotropy(app, eigw(:,i), eigv(:,i));
                condition(app, eigw(:,i), eigv(:,i));
                volume(app, Aw(:,:,i), Av(:,:,i));
                
                temp = rotm2axang(FK_array(1:3,1:3,i));
                theta_now = temp(4);
                axis_now = temp(1:3);
                       
                app.wXEditField_2.Value = round(axis_now(1),3);
                app.wYEditField_2.Value = round(axis_now(2),3);
                app.wZEditField_2.Value = round(axis_now(3),3);
                
                app.ThetaradianEditField_2.Value = theta_now;


            end


            app.theta = theta;


        end

        % Value changed function: EllipsoidSwitch
        function EllipsoidSwitchValueChanged(app, event)
            value = app.EllipsoidSwitch.Value;
            if strcmp(value, "On")
                app.toggleEllipsoid = 1;
            else
                app.toggleEllipsoid = 0;
            end
        end

        % Value changed function: ScrewAxesSwitch
        function ScrewAxesSwitchValueChanged(app, event)
            value = app.ScrewAxesSwitch.Value;
            if strcmp(value, "On")
                app.toggleScrewAxis = 1;
            else
                app.toggleScrewAxis = 0;
            end
            
        end

        % Value changed function: JointCoordSwitch
        function JointCoordSwitchValueChanged(app, event)
            value = app.JointCoordSwitch.Value;
            if strcmp(value, "On")
                app.toggleJointCoord = 1;
            else
                app.toggleJointCoord = 0;
            end
            
        end

        % Value changed function: EndEffectorCoordSwitch
        function EndEffectorCoordSwitchValueChanged(app, event)
            value = app.EndEffectorCoordSwitch.Value;
            if strcmp(value, "On")
                app.toggleEndEffectorCoord= 1;
            else
                app.toggleEndEffectorCoord = 0;
            end
            
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            app.theta = [0 0 0 0 0 0 0];
            

            T_space_array = zeros(4,4,8);
            J_array = zeros(6,7);
            FK_array = zeros(4,4);
            

            [FK_array(:,:), T_space_array(:,:,:)] = FK_space(app,app.q, app.w, app.theta, app.M);

            J_array(:,:) = J_space(app,app.q, app.w, app.theta, app.M);

            
            % ----------------------------------------------------draw robot -----------------------------------------------------
            
     
            drawRoboArm(app, T_space_array(:,:,:));
            hold(app.UIAxes, "on")
            if app.toggleEllipsoid == 1
                manip_ellip(app, J_array(:,:), FK_array(:,:));
            end
            
            if app.toggleEndEffectorCoord == 1
                drawEndEffecotr(app, T_space_array(:,:,:))
            end
            
            if app.toggleJointCoord == 1
                drawJointCoord(app, T_space_array(:,:,:))
            end
            hold(app.UIAxes, "off")
            
            app.Theta1EditField.Value = 0;
            app.Theta2EditField.Value = 0;
            app.Theta3EditField.Value = 0;
            app.Theta4EditField.Value = 0;
            app.Theta5EditField.Value = 0;
            app.Theta6EditField.Value = 0;
            app.Theta7EditField.Value = 0;

            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.Lamp.Color = 'red';
            pause(0.1)
            w1 = app.wXEditField.Value;
            w2 = app.wYEditField.Value;
            w3 = app.wZEditField.Value;
            
            p1 = app.XEditField.Value;
            p2 = app.YEditField.Value;
            p3 = app.ZEditField.Value;
            
            theta = app.ThetaradianEditField.Value;
            
            w = [w1 w2 w3];
            w = w/norm(w);
            p = [p1; p2; p3];
            config_desired = Tmat(app,w,p,theta);
            
            theta1 = app.Theta1EditField.Value;
            theta2 = app.Theta2EditField.Value;
            theta3 = app.Theta3EditField.Value;
            theta4 = app.Theta4EditField.Value;
            theta5 = app.Theta5EditField.Value;
            theta6 = app.Theta6EditField.Value;
            theta7 = app.Theta7EditField.Value;
            
            init_theta = [theta1, theta2, theta3, theta4, theta5, theta6, theta7];
            
            
            temp = J_inverse_kinematics(app, app.q, app.w, init_theta, app.M, config_desired);
            
            for i = 1:7
                if temp(i)>=2*pi || temp(i)<= -2*pi
                    temp(i) = mod(temp(i), 2*pi);
                end
            end
            
            app.theta_inv = temp;
  
            
            app.Lamp.Color = 'green';


        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            steps = 20;
            
            theta = app.theta_inv';
            dtheta = (theta - app.theta)/steps;
            
            
            T_space_array = zeros(4,4,8,steps);
            J_array = zeros(6,7,steps);
            FK_array = zeros(4,4,steps);
            Aw = zeros(3,3,steps);
            Av = zeros(3,3,steps);
            eigw = zeros(3,steps);
            eigv = zeros(3,steps);
            
            for i = 1:steps
            [FK_array(:,:,i), T_space_array(:,:,:,i)] = FK_space(app,app.q, app.w, app.theta + i*dtheta, app.M);
            end
            
            for i = 1:steps
            J_array(:,:,i) = J_space(app,app.q, app.w, app.theta + i*dtheta, app.M);
            end
            
            for i = 1:steps
                Jw =  J_array(1:3,:,i);
                Jv =  J_array(4:6,:,i);
                Aw(:,:,i) = Jw*Jw';
                Av(:,:,i) = Jv*Jv';
            end
            
            for i = 1:steps
                [~, a] = eig(Aw(:,:,i));
                [~, b] = eig(Av(:,:,i));
                eigw(:,i) = [a(1,1); a(2,2); a(3,3)];
                eigv(:,i) = [b(1,1); b(2,2); b(3,3)];
            end
            
            % ----------------------------------------------------draw robot -----------------------------------------------------
            
           
            for i = 1:steps
               
                drawRoboArm(app, T_space_array(:,:,:,i));
                hold(app.UIAxes, "on")
                if app.toggleEllipsoid == 1
                    manip_ellip(app, J_array(:,:,i), FK_array(:,:,i));
                end
                
                
                if app.toggleEndEffectorCoord == 1
                    drawEndEffecotr(app, T_space_array(:,:,:,i))
                end
                
                if app.toggleJointCoord == 1
                    drawJointCoord(app, T_space_array(:,:,:,i))
                end
                hold(app.UIAxes, "off")
                drawnow limitrate nocallbacks
                    
                H = FK_array(:,:,i);
                app.XEditField_3.Value = H(1,4);
                app.YEditField_3.Value = H(2,4);
                app.ZEditField_3.Value = H(3,4);
                
              
                isotropy(app, eigw(:,i), eigv(:,i));
                condition(app, eigw(:,i), eigv(:,i));
                volume(app, Aw(:,:,i), Av(:,:,i));

                if FK_array(1:3,1:3,i) == eye(3)
                    app.wXEditField_2.Value = app.wXEditField.Value;
                    app.wYEditField_2.Value = app.wYEditField.Value;
                    app.wZEditField_2.Value = app.wZEditField.Value;
                end
                [thetak, axis] = getRotationReps(FK_array(1:3,1:3,i));
                theta_now = thetak;
                axis_now = axis/norm(axis);
                       
                app.wXEditField_2.Value = round(axis_now(1),3);
                app.wYEditField_2.Value = round(axis_now(2),3);
                app.wZEditField_2.Value = round(axis_now(3),3);
                
                app.ThetaradianEditField_2.Value = round(theta_now,4);
                    

            end
          

            app.theta = theta;
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            app.Lamp_2.Color = 'red';
            pause(0.1)
            w1 = app.wXEditField.Value;
            w2 = app.wYEditField.Value;
            w3 = app.wZEditField.Value;
            
            p1 = app.XEditField.Value;
            p2 = app.YEditField.Value;
            p3 = app.ZEditField.Value;
            
            theta = app.ThetaradianEditField.Value;
            
            w = [w1 w2 w3];
            w = w/norm(w);
            p = [p1; p2; p3];
            config_desired = Tmat(app,w,p,theta);
            
            theta1 = app.Theta1EditField.Value;
            theta2 = app.Theta2EditField.Value;
            theta3 = app.Theta3EditField.Value;
            theta4 = app.Theta4EditField.Value;
            theta5 = app.Theta5EditField.Value;
            theta6 = app.Theta6EditField.Value;
            theta7 = app.Theta7EditField.Value;
            
            init_theta = [theta1, theta2, theta3, theta4, theta5, theta6, theta7];
            
            
            temp = J_transpose_kinematics(app, app.q, app.w, init_theta, app.M, config_desired);
            
            for i = 1:7
                if temp(i)>=2*pi || temp(i)<= -2*pi
                    temp(i) = mod(temp(i), 2*pi);
                end
            end
            
            app.theta_trans = temp;
            
            app.Lamp_2.Color = 'green';
            
        end

        % Button pushed function: GraphButton_2
        function GraphButton_2Pushed(app, event)
             steps = 20;
            
            theta = app.theta_trans';
            dtheta = (theta - app.theta)/steps;
            
            
            T_space_array = zeros(4,4,8,steps);
            J_array = zeros(6,7,steps);
            FK_array = zeros(4,4,steps);
            Aw = zeros(3,3,steps);
            Av = zeros(3,3,steps);
            eigw = zeros(3,steps);
            eigv = zeros(3,steps);
            
            for i = 1:steps
            [FK_array(:,:,i), T_space_array(:,:,:,i)] = FK_space(app,app.q, app.w, app.theta + i*dtheta, app.M);
            end
            
            for i = 1:steps
            J_array(:,:,i) = J_space(app,app.q, app.w, app.theta + i*dtheta, app.M);
            end
            
            for i = 1:steps
                Jw =  J_array(1:3,:,i);
                Jv =  J_array(4:6,:,i);
                Aw(:,:,i) = Jw*Jw';
                Av(:,:,i) = Jv*Jv';
            end
            
            for i = 1:steps
                [~, a] = eig(Aw(:,:,i));
                [~, b] = eig(Av(:,:,i));
                eigw(:,i) = [a(1,1); a(2,2); a(3,3)];
                eigv(:,i) = [b(1,1); b(2,2); b(3,3)];
            end
            
            % ----------------------------------------------------draw robot -----------------------------------------------------
            
           
            for i = 1:steps
               
                drawRoboArm(app, T_space_array(:,:,:,i));
                hold(app.UIAxes, "on")
                if app.toggleEllipsoid == 1
                    manip_ellip(app, J_array(:,:,i), FK_array(:,:,i));
                end
                
                
                if app.toggleEndEffectorCoord == 1
                    drawEndEffecotr(app, T_space_array(:,:,:,i))
                end
                
                if app.toggleJointCoord == 1
                    drawJointCoord(app, T_space_array(:,:,:,i))
                end
                hold(app.UIAxes, "off")
                drawnow limitrate nocallbacks
                    
                H = FK_array(:,:,i);
                app.XEditField_3.Value = H(1,4);
                app.YEditField_3.Value = H(2,4);
                app.ZEditField_3.Value = H(3,4);
                
              
                isotropy(app, eigw(:,i), eigv(:,i));
                condition(app, eigw(:,i), eigv(:,i));
                volume(app, Aw(:,:,i), Av(:,:,i));
                
                temp = rotm2axang(FK_array(1:3,1:3,i));
                theta_now = temp(4);
                axis_now = temp(1:3);
                       
                app.wXEditField_2.Value = round(axis_now(1),3);
                app.wYEditField_2.Value = round(axis_now(2),3);
                app.wZEditField_2.Value = round(axis_now(3),3);
                
                app.ThetaradianEditField_2.Value = theta_now;
                    

            end
          

            app.theta = theta;
        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
            app.Lamp_3.Color = 'red';
            pause(0.1)
            w1 = app.wXEditField.Value;
            w2 = app.wYEditField.Value;
            w3 = app.wZEditField.Value;
            
            p1 = app.XEditField.Value;
            p2 = app.YEditField.Value;
            p3 = app.ZEditField.Value;
            
            theta = app.ThetaradianEditField.Value;
            
            w = [w1 w2 w3];
            w = w/norm(w);
            p = [p1; p2; p3];
            config_desired = Tmat(app,w,p,theta);
            
            theta1 = app.Theta1EditField.Value;
            theta2 = app.Theta2EditField.Value;
            theta3 = app.Theta3EditField.Value;
            theta4 = app.Theta4EditField.Value;
            theta5 = app.Theta5EditField.Value;
            theta6 = app.Theta6EditField.Value;
            theta7 = app.Theta7EditField.Value;
            
            init_theta = [theta1, theta2, theta3, theta4, theta5, theta6, theta7];
            
            
            temp = redundancy_resolution2(app, app.q, app.w, init_theta, app.M, config_desired);
            
            for i = 1:7
                if temp(i)>=2*pi || temp(i)<= -2*pi
                    temp(i) = mod(temp(i), 2*pi);
                end
            end
            
            app.theta_redund = temp;
            
            app.Lamp_3.Color = 'green';
        end

        % Button pushed function: GraphButton_3
        function GraphButton_3Pushed(app, event)
            steps = 20;
            
            theta = app.theta_redund';
            dtheta = (theta - app.theta)/steps;
            
            
            T_space_array = zeros(4,4,8,steps);
            J_array = zeros(6,7,steps);
            FK_array = zeros(4,4,steps);
            Aw = zeros(3,3,steps);
            Av = zeros(3,3,steps);
            eigw = zeros(3,steps);
            eigv = zeros(3,steps);
            
            for i = 1:steps
            [FK_array(:,:,i), T_space_array(:,:,:,i)] = FK_space(app,app.q, app.w, app.theta + i*dtheta, app.M);
            end
            
            for i = 1:steps
            J_array(:,:,i) = J_space(app,app.q, app.w, app.theta + i*dtheta, app.M);
            end
            
            for i = 1:steps
                Jw =  J_array(1:3,:,i);
                Jv =  J_array(4:6,:,i);
                Aw(:,:,i) = Jw*Jw';
                Av(:,:,i) = Jv*Jv';
            end
            
            for i = 1:steps
                [~, a] = eig(Aw(:,:,i));
                [~, b] = eig(Av(:,:,i));
                eigw(:,i) = [a(1,1); a(2,2); a(3,3)];
                eigv(:,i) = [b(1,1); b(2,2); b(3,3)];
            end
            
            % ----------------------------------------------------draw robot -----------------------------------------------------
            
           
            for i = 1:steps
               
                drawRoboArm(app, T_space_array(:,:,:,i));
                hold(app.UIAxes, "on")
                if app.toggleEllipsoid == 1
                    manip_ellip(app, J_array(:,:,i), FK_array(:,:,i));
                end
                
                
                if app.toggleEndEffectorCoord == 1
                    drawEndEffecotr(app, T_space_array(:,:,:,i))
                end
                
                if app.toggleJointCoord == 1
                    drawJointCoord(app, T_space_array(:,:,:,i))
                end
                hold(app.UIAxes, "off")
                drawnow limitrate nocallbacks
                    
                H = FK_array(:,:,i);
                app.XEditField_3.Value = H(1,4);
                app.YEditField_3.Value = H(2,4);
                app.ZEditField_3.Value = H(3,4);
                
              
                isotropy(app, eigw(:,i), eigv(:,i));
                condition(app, eigw(:,i), eigv(:,i));
                volume(app, Aw(:,:,i), Av(:,:,i));
                
                temp = rotm2axang(FK_array(1:3,1:3,i));
                theta_now = temp(4);
                axis_now = temp(1:3);
                       
                app.wXEditField_2.Value = round(axis_now(1),3);
                app.wYEditField_2.Value = round(axis_now(2),3);
                app.wZEditField_2.Value = round(axis_now(3),3);
                
                app.ThetaradianEditField_2.Value = theta_now;
                    

            end
          

            app.theta = theta;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.Theta1EditFieldLabel = uilabel(app.UIFigure);
            app.Theta1EditFieldLabel.Text = 'Theta1';
            app.Theta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta2EditFieldLabel = uilabel(app.UIFigure);
            app.Theta2EditFieldLabel.Text = 'Theta2';
            app.Theta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta3EditFieldLabel = uilabel(app.UIFigure);
            app.Theta3EditFieldLabel.Text = 'Theta3';
            app.Theta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta4EditFieldLabel = uilabel(app.UIFigure);
            app.Theta4EditFieldLabel.Text = 'Theta4';
            app.Theta4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta5EditFieldLabel = uilabel(app.UIFigure);
            app.Theta5EditFieldLabel.Text = 'Theta5';
            app.Theta5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta6EditFieldLabel = uilabel(app.UIFigure);
            app.Theta6EditFieldLabel.Text = 'Theta6';
            app.Theta6EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta7EditFieldLabel = uilabel(app.UIFigure);
            app.Theta7EditFieldLabel.Text = 'Theta7';
            app.Theta7EditField = uieditfield(app.UIFigure, 'numeric');
            app.DisplayOptionsPanel = uipanel(app.UIFigure);
            app.DisplayOptionsPanel.Title = 'Display Options';
            app.EllipsoidSwitchLabel = uilabel(app.DisplayOptionsPanel);
            app.EllipsoidSwitchLabel.Text = 'Ellipsoid';
            app.EllipsoidSwitch = uiswitch(app.DisplayOptionsPanel, 'slider');
            app.EllipsoidSwitch.ValueChangedFcn = createCallbackFcn(app, @EllipsoidSwitchValueChanged, true);
            app.ScrewAxesSwitchLabel = uilabel(app.DisplayOptionsPanel);
            app.ScrewAxesSwitchLabel.Text = 'Screw Axes';
            app.ScrewAxesSwitch = uiswitch(app.DisplayOptionsPanel, 'slider');
            app.ScrewAxesSwitch.ValueChangedFcn = createCallbackFcn(app, @ScrewAxesSwitchValueChanged, true);
            app.JointCoordSwitchLabel = uilabel(app.DisplayOptionsPanel);
            app.JointCoordSwitchLabel.Text = 'Joint Coord';
            app.JointCoordSwitch = uiswitch(app.DisplayOptionsPanel, 'slider');
            app.JointCoordSwitch.ValueChangedFcn = createCallbackFcn(app, @JointCoordSwitchValueChanged, true);
            app.EndEffectorCoordSwitchLabel = uilabel(app.DisplayOptionsPanel);
            app.EndEffectorCoordSwitchLabel.Text = 'End-Effector Coord';
            app.EndEffectorCoordSwitch = uiswitch(app.DisplayOptionsPanel, 'slider');
            app.EndEffectorCoordSwitch.ValueChangedFcn = createCallbackFcn(app, @EndEffectorCoordSwitchValueChanged, true);
            app.DefaultButton = uibutton(app.UIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.AngularEllipsoidPanel = uipanel(app.UIFigure);
            app.AngularEllipsoidPanel.Title = 'Angular Ellipsoid ';
            app.IsotropyEditFieldLabel = uilabel(app.AngularEllipsoidPanel);
            app.IsotropyEditFieldLabel.Text = 'Isotropy';
            app.IsotropyEditField = uieditfield(app.AngularEllipsoidPanel, 'numeric');
            app.CondtitionEditFieldLabel = uilabel(app.AngularEllipsoidPanel);
            app.CondtitionEditFieldLabel.Text = 'Condtition';
            app.CondtitionEditField = uieditfield(app.AngularEllipsoidPanel, 'numeric');
            app.VolumeEditFieldLabel = uilabel(app.AngularEllipsoidPanel);
            app.VolumeEditFieldLabel.Text = 'Volume';
            app.VolumeEditField = uieditfield(app.AngularEllipsoidPanel, 'numeric');
            app.LinearEllipsoidPanel = uipanel(app.UIFigure);
            app.LinearEllipsoidPanel.Title = 'Linear Ellipsoid ';
            app.VolumeEditField_2Label = uilabel(app.LinearEllipsoidPanel);
            app.VolumeEditField_2Label.Text = 'Volume';
            app.VolumeEditField_2 = uieditfield(app.LinearEllipsoidPanel, 'numeric');
            app.CondtitionEditField_2Label = uilabel(app.LinearEllipsoidPanel);
            app.CondtitionEditField_2Label.Text = 'Condtition';
            app.CondtitionEditField_2 = uieditfield(app.LinearEllipsoidPanel, 'numeric');
            app.IsotropyEditField_2Label = uilabel(app.LinearEllipsoidPanel);
            app.IsotropyEditField_2Label.Text = 'Isotropy';
            app.IsotropyEditField_2 = uieditfield(app.LinearEllipsoidPanel, 'numeric');
            app.DesiredconfigurationPanel = uipanel(app.UIFigure);
            app.DesiredconfigurationPanel.Title = 'Desired configuration';
            app.XEditFieldLabel = uilabel(app.DesiredconfigurationPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.DesiredconfigurationPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.DesiredconfigurationPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.DesiredconfigurationPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.DesiredconfigurationPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.DesiredconfigurationPanel, 'numeric');
            app.wXEditFieldLabel = uilabel(app.DesiredconfigurationPanel);
            app.wXEditFieldLabel.Text = 'wX';
            app.wXEditField = uieditfield(app.DesiredconfigurationPanel, 'numeric');
            app.wYEditFieldLabel = uilabel(app.DesiredconfigurationPanel);
            app.wYEditFieldLabel.Text = 'wY';
            app.wYEditField = uieditfield(app.DesiredconfigurationPanel, 'numeric');
            app.wZEditFieldLabel = uilabel(app.DesiredconfigurationPanel);
            app.wZEditFieldLabel.Text = 'wZ';
            app.wZEditField = uieditfield(app.DesiredconfigurationPanel, 'numeric');
            app.ThetaradianLabel = uilabel(app.DesiredconfigurationPanel);
            app.ThetaradianLabel.Text = 'Theta (radian)';
            app.ThetaradianEditField = uieditfield(app.DesiredconfigurationPanel, 'numeric');
            app.JacobianInversePanel = uipanel(app.UIFigure);
            app.JacobianInversePanel.Title = 'Jacobian Inverse';
            app.CalculateButton = uibutton(app.JacobianInversePanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.GraphButton = uibutton(app.JacobianInversePanel, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.RedundancyresolutionPanel = uipanel(app.UIFigure);
            app.RedundancyresolutionPanel.Title = 'Redundancy resolution';
            app.CalculateButton_3 = uibutton(app.RedundancyresolutionPanel, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.GraphButton_3 = uibutton(app.RedundancyresolutionPanel, 'push');
            app.GraphButton_3.ButtonPushedFcn = createCallbackFcn(app, @GraphButton_3Pushed, true);
            app.GraphButton_3.Text = 'Graph';
            app.JacobianTransposePanel = uipanel(app.UIFigure);
            app.JacobianTransposePanel.Title = 'Jacobian Transpose';
            app.CalculateButton_2 = uibutton(app.JacobianTransposePanel, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.GraphButton_2 = uibutton(app.JacobianTransposePanel, 'push');
            app.GraphButton_2.ButtonPushedFcn = createCallbackFcn(app, @GraphButton_2Pushed, true);
            app.GraphButton_2.Text = 'Graph';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.EndEffectorConfigurationPanel = uipanel(app.UIFigure);
            app.EndEffectorConfigurationPanel.Title = 'End-Effector Configuration';
            app.XEditField_3Label = uilabel(app.EndEffectorConfigurationPanel);
            app.XEditField_3Label.Text = 'X';
            app.XEditField_3 = uieditfield(app.EndEffectorConfigurationPanel, 'numeric');
            app.YEditField_3Label = uilabel(app.EndEffectorConfigurationPanel);
            app.YEditField_3Label.Text = 'Y';
            app.YEditField_3 = uieditfield(app.EndEffectorConfigurationPanel, 'numeric');
            app.ZEditField_3Label = uilabel(app.EndEffectorConfigurationPanel);
            app.ZEditField_3Label.Text = 'Z';
            app.ZEditField_3 = uieditfield(app.EndEffectorConfigurationPanel, 'numeric');
            app.wXEditField_2Label = uilabel(app.EndEffectorConfigurationPanel);
            app.wXEditField_2Label.Text = 'wX';
            app.wXEditField_2 = uieditfield(app.EndEffectorConfigurationPanel, 'numeric');
            app.wYEditField_2Label = uilabel(app.EndEffectorConfigurationPanel);
            app.wYEditField_2Label.Text = 'wY';
            app.wYEditField_2 = uieditfield(app.EndEffectorConfigurationPanel, 'numeric');
            app.wZEditField_2Label = uilabel(app.EndEffectorConfigurationPanel);
            app.wZEditField_2Label.Text = 'wZ';
            app.wZEditField_2 = uieditfield(app.EndEffectorConfigurationPanel, 'numeric');
            app.ThetaradianEditField_2Label = uilabel(app.EndEffectorConfigurationPanel);
            app.ThetaradianEditField_2Label.Text = 'Theta (radian)';
            app.ThetaradianEditField_2 = uieditfield(app.EndEffectorConfigurationPanel, 'numeric');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Error ')
            xlabel(app.UIAxes2, 'Steps')
            ylabel(app.UIAxes2, 'Error')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Ellipsoid Volume')
            xlabel(app.UIAxes3, 'Steps')
            ylabel(app.UIAxes3, 'Volume')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoboArmSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end